classdef Senior_Design_Testbed < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        File                        matlab.ui.container.Menu
        SaveConfigMenu              matlab.ui.container.Menu
        LoadConfigMenu              matlab.ui.container.Menu
        Help                        matlab.ui.container.Menu
        AboutMenu                   matlab.ui.container.Menu
        ControlNetworkConfigurationPanel  matlab.ui.container.Panel
        AddNodesButton              matlab.ui.control.Button
        MultiNodesCheckBox          matlab.ui.control.CheckBox
        AddressEditFieldLabel       matlab.ui.control.Label
        MultiNodesAddressEditField  matlab.ui.control.NumericEditField
        AddressEditField            matlab.ui.control.NumericEditField
        toEditFieldLabel            matlab.ui.control.Label
        SubnetEditFieldLabel        matlab.ui.control.Label
        SubnetEditField             matlab.ui.control.EditField
        NodeStatusPanel             matlab.ui.container.Panel
        NodeTable                   matlab.ui.control.Table
        RefreshNodesButton          matlab.ui.control.Button
        RemoveNodesButton           matlab.ui.control.Button
        SelectAllButton             matlab.ui.control.Button
        ConfirmDeletionPanel        matlab.ui.container.Panel
        ConfirmDeletionofselectedPisLabel  matlab.ui.control.Label
        ConfirmButton               matlab.ui.control.StateButton
        CancelButton                matlab.ui.control.StateButton
    end

    
    properties (Access = private)
        Address_Col = 2; % Control Address column in Node table
        Version = 0.1;
    end
    
    methods (Access = private)
        
        % Sort the Node Table by Control Address
        function results = SortNodeTable(app) %#ok<STOUT> 
            % Get the Subnet Address
            Subnet = num2str(app.SubnetEditField.Value);
            Subnet = erase(Subnet,'X');
            
            % NOTE: Sort on number rather than string to mitigate X.X.X.10
            % before X.X.X.2 (etc.)
            FullTable = app.NodeTable.Data;
            if(isempty(FullTable)==0)
                FullTable_Addresses = erase(FullTable(:,app.Address_Col),Subnet);
                temp = str2num(strjoin(FullTable_Addresses)); %#ok<ST2NM> 
                [~,ix] = sort(temp);
                FullTable = FullTable(ix,:);
                app.NodeTable.Data = FullTable;            
            end
        end
        
        % Check the address inputs to maintain valid range
        function err = VerifyAddressRange(app)
            err = 0; % TODO: Add warning messages
            
            % Verify Min Device address
            DA_min = app.AddressEditField.Value;
            if (DA_min < 0)
                app.AddressEditField.Value = 0;
            elseif (DA_min > 255)
                app.AddressEditField.Value = 255;
            end
            
            % Verify Max Device address
            DA_min = app.AddressEditField.Value; % In case updated above
            DA_max = app.MultiNodesAddressEditField.Value;
            if (app.MultiNodesAddressEditField.Enable)
                if (DA_max < DA_min)
                    app.MultiNodesAddressEditField.Value = DA_min;
                end            
                
                if (DA_max > 255)
                    app.MultiNodesAddressEditField.Value = 255;
                end                        
            end
            
            % TODO: Verify subnet address
        end
%         function ipstatus = VerifyStatus(app, DA_min, DA_max)
%             command = 'bash get_ip_new.sh';
%             app = append(command, ' ', string(DA_min), ' ', string(DA_max));
%             ip = {evalc('system(app)')};
%             ip_spl = split(ip); 
%             ip_fin = ip_spl(1:end-4,:);
%             save('ip_save.mat', 'ip_fin')
%         end
        function results = getNewRow(app, Address) 
            DA_min = app.AddressEditField.Value; % In case updated above
            DA_max = app.MultiNodesAddressEditField.Value;
            command = 'bash get_ip_new.sh';
            meh = append(command, ' ', string(DA_min), ' ', string(DA_max));
            ip = {evalc('system(meh)')};
            ip_spl = split(ip); 
            ip_fin = ip_spl(1:end-4,:);
            ip_fin = char(ip_fin);
            save('ip_save.mat', 'ip_fin')
            results = {true Address ip_fin 'X' 'X'};
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Value changed function: MultiNodesCheckBox
        function MultiNodesCheckBoxValueChanged(app, event)
            value = app.MultiNodesCheckBox.Value;
            if (value)
                app.MultiNodesAddressEditField.Enable = 1;
                app.MultiNodesAddressEditField.Value = app.AddressEditField.Value;
            else
                app.MultiNodesAddressEditField.Enable = 0;
                app.MultiNodesAddressEditField.Value = 0;
            end
        end

        % Button pushed function: AddNodesButton
        function AddNodesButtonPushed(app, event)
            % Get the Subnet Address
            Subnet = num2str(app.SubnetEditField.Value);
            Subnet = erase(Subnet,'X');
            
            % Get the Device Address range
            Device_Min = app.AddressEditField.Value;
            Device_Max = app.MultiNodesAddressEditField.Value;
            
            % Generate new table strings
            newData = [];
            for Device_address = Device_Min:max(Device_Min,Device_Max)
                Device = num2str(Device_address);
                Address = [Subnet Device];
                
                % Check the table for existing address. 
                if isempty(app.NodeTable.Data)
                    newData = [newData;app.getNewRow(Address)]; %#ok<AGROW>
                elseif (endsWith(app.NodeTable.Data(:,app.Address_Col), Address) == 0)
                    %NOTE: Use endswith to avoid issues with finding 
                    % 'a.b.c.100' when adding 'a.b.c.1'
                    newData = [newData;app.getNewRow(Address)]; %#ok<AGROW>
                end                
            end

            % TODO: Add code to get remaining table data.
            % Potentially do this ONLY when refreshing node status?
            % Move this to function
            ip_command_start = 'bash get_ip_new_sd.sh';
            ip_command = append(ip_command_start, ' ', string(app.AddressEditField.Value), ' ', string(app.MultiNodesAddressEditField.Value));
            ip = {evalc('system(ip_command)')};
            ip = split(ip); 
            ip = ip(1:end-4,:); 
            
            newData(:,3) = ip;

            % Add newData to table (sorted with new data)
            app.NodeTable.Data = [app.NodeTable.Data;newData];
            app.SortNodeTable();
            
        end

        % Value changed function: SubnetEditField
        function SubnetEditFieldValueChanged(app, event)
            %value = app.SubnetEditField.Value;
            app.VerifyAddressRange();
        end

        % Value changed function: AddressEditField
        function AddressEditFieldValueChanged(app, event)
            %value = app.AddressEditField.Value;
            app.VerifyAddressRange();
        end

        % Value changed function: MultiNodesAddressEditField
        function MultiNodesAddressEditFieldValueChanged(app, event)
            %value = app.MultiNodesAddressEditField.Value;
            app.VerifyAddressRange();
        end

        % Button pushed function: RemoveNodesButton
        function RemoveNodesButtonPushed(app, event)
            %TODO: Add warning to confirm removing X nodes
            app.ConfirmDeletionPanel.Visible = 'on';
            %if(isempty(app.NodeTable.Data)==0)
                % Remove all selected items from table
                %app.NodeTable.Data = app.NodeTable.Data(~cell2mat(app.NodeTable.Data(:,1)),:);
            %end            
        end

        % Button pushed function: SelectAllButton
        function SelectAllButtonPushed(app, event)
            if (all(cell2mat(app.NodeTable.Data(:,1))))
                % If all nodes are selected, deselect
                app.NodeTable.Data(:,1) = {false};
            else
                app.NodeTable.Data(:,1) = {true};
            end
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            msgbox({['Version: ' num2str(app.Version)]; ...
                    'About: This is an interface for the UCaN Lab Rasberry Pi Testbed'; ...
                    'Ubiquitous Communications and Networking Lab (UCaN Lab)'; ...
                    'University of Massachusetts, Boston'}, 'About');
        end

        % Menu selected function: SaveConfigMenu
        function SaveConfigMenuSelected(app, event)
            [file,path] = uiputfile({'*.mat'});
            if (file ~= 0)
                myVersion = app.Version;
                Nodes = app.NodeTable.Data(:,app.Address_Col);
                
                if (exist([path file],'file'))
                    warndlg('Existing File, can not overwrite')
                else
                    save([path file],'myVersion','Nodes')
                end
            end
        end

        % Menu selected function: LoadConfigMenu
        function LoadConfigMenuSelected(app, event)
            [file,path] = uigetfile({'*.mat'});
            
            if (file ~= 0)
                load([path file],'myVersion','Nodes');
                % Check for version compatibility
                if (myVersion == app.Version)
                    % Generate new table strings
                    newData = [];
                    for iter = 1:length(Nodes)
                        Address = char(Nodes(iter));
                        newData = [newData;app.getNewRow(Address)]; %#ok<AGROW>
                    end
        
                    % TODO: Add code to get remaining table data.
                    % Potentially do this ONLY when refreshing node status?
                    
                    % Add newData to table (sorted with new data)
                    app.NodeTable.Data = newData;
                    app.SortNodeTable();                    
                    
                else
                    warndlg('Version mismatch')
                end
            end
            
        end

        % Value changed function: ConfirmButton
        function ConfirmButtonValueChanged(app, event)
            if(isempty(app.NodeTable.Data)==0)
                % Remove all selected items from table
                app.NodeTable.Data = app.NodeTable.Data(~cell2mat(app.NodeTable.Data(:,1)),:);
            end
            app.ConfirmDeletionPanel.Visible = 'off';
        end

        % Value changed function: CancelButton
        function CancelButtonValueChanged(app, event)
           app.ConfirmDeletionPanel.Visible = 'off';
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.File = uimenu(app.UIFigure);
            app.File.Text = 'File';
            app.SaveConfigMenu = uimenu(app.File);
            app.SaveConfigMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveConfigMenuSelected, true);
            app.SaveConfigMenu.Text = 'Save Configuration';
            app.LoadConfigMenu = uimenu(app.File);
            app.LoadConfigMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadConfigMenuSelected, true);
            app.LoadConfigMenu.Text = 'Load Configuration';
            app.Help = uimenu(app.UIFigure);
            app.Help.Text = 'Help';
            app.AboutMenu = uimenu(app.Help);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'About';
            app.ControlNetworkConfigurationPanel = uipanel(app.UIFigure);
            app.ControlNetworkConfigurationPanel.Title = 'Control Network Configuration';
            app.AddNodesButton = uibutton(app.ControlNetworkConfigurationPanel, 'push');
            app.AddNodesButton.ButtonPushedFcn = createCallbackFcn(app, @AddNodesButtonPushed, true);
            app.AddNodesButton.Text = 'Add Node(s)';
            app.MultiNodesCheckBox = uicheckbox(app.ControlNetworkConfigurationPanel);
            app.MultiNodesCheckBox.ValueChangedFcn = createCallbackFcn(app, @MultiNodesCheckBoxValueChanged, true);
            app.MultiNodesCheckBox.Text = 'Add Multiple Nodes';
            app.AddressEditFieldLabel = uilabel(app.ControlNetworkConfigurationPanel);
            app.AddressEditFieldLabel.Text = 'Address';
            app.MultiNodesAddressEditField = uieditfield(app.ControlNetworkConfigurationPanel, 'numeric');
            app.MultiNodesAddressEditField.ValueChangedFcn = createCallbackFcn(app, @MultiNodesAddressEditFieldValueChanged, true);
            app.MultiNodesAddressEditField.Enable = 'off';
            app.AddressEditField = uieditfield(app.ControlNetworkConfigurationPanel, 'numeric');
            app.AddressEditField.ValueChangedFcn = createCallbackFcn(app, @AddressEditFieldValueChanged, true);
            app.toEditFieldLabel = uilabel(app.ControlNetworkConfigurationPanel);
            app.toEditFieldLabel.Enable = 'off';
            app.toEditFieldLabel.Text = 'to';
            app.SubnetEditFieldLabel = uilabel(app.ControlNetworkConfigurationPanel);
            app.SubnetEditFieldLabel.Text = 'Subnet';
            app.SubnetEditField = uieditfield(app.ControlNetworkConfigurationPanel, 'text');
            app.SubnetEditField.ValueChangedFcn = createCallbackFcn(app, @SubnetEditFieldValueChanged, true);
            app.SubnetEditField.Editable = 'off';
            app.SubnetEditField.Value = '192.168.1.X';
            app.NodeStatusPanel = uipanel(app.UIFigure);
            app.NodeStatusPanel.Title = 'Node Status';
            app.NodeTable = uitable(app.NodeStatusPanel);
            app.NodeTable.ColumnName = {'Select'; 'Device Address'; 'Status'; 'USRP Serial'; 'Mobility Status'};
            app.NodeTable.RowName = {};
            app.NodeTable.ColumnEditable = [true false false false false false];
            app.RefreshNodesButton = uibutton(app.NodeStatusPanel, 'push');
            app.RefreshNodesButton.Text = 'Refresh Nodes';
            app.RemoveNodesButton = uibutton(app.NodeStatusPanel, 'push');
            app.RemoveNodesButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveNodesButtonPushed, true);
            app.RemoveNodesButton.Text = 'Remove Selected Node(s)';
            app.SelectAllButton = uibutton(app.NodeStatusPanel, 'push');
            app.SelectAllButton.ButtonPushedFcn = createCallbackFcn(app, @SelectAllButtonPushed, true);
            app.SelectAllButton.Text = 'Select All';
            app.ConfirmDeletionPanel = uipanel(app.NodeStatusPanel);
            app.ConfirmDeletionPanel.Title = 'Confirm Deletion';
            app.ConfirmDeletionPanel.Visible = 'off';
            app.ConfirmDeletionofselectedPisLabel = uilabel(app.ConfirmDeletionPanel);
            app.ConfirmDeletionofselectedPisLabel.Text = 'Confirm Deletion of selected Pi''s';
            app.ConfirmButton = uibutton(app.ConfirmDeletionPanel, 'state');
            app.ConfirmButton.ValueChangedFcn = createCallbackFcn(app, @ConfirmButtonValueChanged, true);
            app.ConfirmButton.Text = 'Confirm';
            app.CancelButton = uibutton(app.ConfirmDeletionPanel, 'state');
            app.CancelButton.ValueChangedFcn = createCallbackFcn(app, @CancelButtonValueChanged, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Senior_Design_Testbed

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end